classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        STOCHASTICPROCESSESCALCULATORLabel  matlab.ui.control.Label
        AmplitudeFileNameegAmplitudetxtEditFieldLabel  matlab.ui.control.Label
        AmplitudeFileEditField      matlab.ui.control.EditField
        TimeFileNameegTimetxtLabel  matlab.ui.control.Label
        TimeFileEditField           matlab.ui.control.EditField
        EnterButton                 matlab.ui.control.Button
        OptionsDropDownLabel        matlab.ui.control.Label
        OptionsDropDown             matlab.ui.control.DropDown
        EnterthenumberofsamplefunctionstobedrawnEditFieldLabel  matlab.ui.control.Label
        M1EditField                 matlab.ui.control.NumericEditField
        EnterthenumberofsamplefunctiontimemeanLabel  matlab.ui.control.Label
        N3EditField                 matlab.ui.control.NumericEditField
        EnterthenumberoffirstsamplefunctionLabel  matlab.ui.control.Label
        I4EditField                 matlab.ui.control.NumericEditField
        EnterthenumberofsecondsamplefunctionLabel  matlab.ui.control.Label
        J4EditField                 matlab.ui.control.NumericEditField
        EnterthenumberofsamplefunctiontimeautocorrelationLabel  matlab.ui.control.Label
        N5EditField                 matlab.ui.control.NumericEditField
        DONEButton                  matlab.ui.control.Button
        ThetotalaveragepoweroftheprocessLabel  matlab.ui.control.Label
        O7EditField                 matlab.ui.control.NumericEditField
        NofileLabel                 matlab.ui.control.Label
        ThetimemeanofthenthsamplefunctionLabel_2  matlab.ui.control.Label
        O3EditField                 matlab.ui.control.NumericEditField
        StatisticalautocorrelationbetweenthetwosamplefunctionsLabel  matlab.ui.control.Label
        O4EditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        X % Description
        t % Description
        ensemble_mean % Description
        timemeanofN % Description
        ACF_IJ % Description
        ACF_N % Description
        En_ACF
        PSD % Description
        Double_ACF % Description
        TimeACF_Mat % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            AmplitudeFile = app.AmplitudeFileEditField.Value;
            TimeFile = app.TimeFileEditField.Value;

            if (exist(AmplitudeFile, 'file') == 0) || (exist(TimeFile, 'file') == 0)
                app.NofileLabel.Visible = 'on';
                app.NofileLabel.Text = 'Files Not Found!';
            else
                app.NofileLabel.Text = 'Files Found';
                app.NofileLabel.Visible = 'off';
                
                app.X= readmatrix(AmplitudeFile);
                app.t = readmatrix(TimeFile);
                
                app.OptionsDropDownLabel.Visible = 'on';
                app.OptionsDropDown.Visible = 'on';
                app.DONEButton.Visible = 'on';
                
                app.M1EditField.Visible = 'on';
                app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'on';
                
                app.AmplitudeFileEditField.Visible = 'off';
                app.AmplitudeFileNameegAmplitudetxtEditFieldLabel.Visible = 'off';
                app.TimeFileEditField.Visible = 'off';
                app.TimeFileNameegTimetxtLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.OptionsDropDown.Value = "Plot 'M' Sample Functions";
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;


            switch value
                case "Plot 'M' Sample Functions"
                    app.M1EditField.Visible = 'on';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'on';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                
                case "Ensemble Mean"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                case "Time Mean of 'nth' Sample Function"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'on';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'on';
                    app.O3EditField.Visible = 'on';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'on';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                case "Statistical Auto-Correlation Function between 'i' and 'j' samples"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'on';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'on';
                    app.I4EditField.Visible = 'on';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'on';
                    app.O4EditField.Visible = 'on';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'on';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                    
                case "ACF between every i and j Samples"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';

                 case "Time Auto-Correlation Function of the 'nth' Sample Function"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'on';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'on';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                case "Power Spectral Density of The Process"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                case "Ensemble ACF"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                case "Total Average Power of The Process"
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'on';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'on';
                    
                case "----ENTER NEW DATA----"
                    
                    app.M1EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.O4EditField.Visible = 'off';
                    app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';


            end
        end

        % Drop down opening function: OptionsDropDown
        function OptionsDropDownOpening(app, event)
               
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            value = app.OptionsDropDown.Value;


            switch value
                case "Plot 'M' Sample Functions"
                    M = app.M1EditField.Value;
                    figure("Name","Sample functions");
                    
                    for i = 1:M
                        if (mod(M,2) ~= 0)
                            if (i == M)
                               subplot(ceil(M/2), 2, [i i+1]); plot(app.t, app.X(i,:)); xlabel('time(sec)');ylabel(['X', num2str(i),'(t)']);
                               title(['Sample function ', num2str(i), ' of the ensemble']); grid on;
                             
                            else
                            subplot(ceil(M/2), 2, i); plot(app.t, app.X(i,:)); xlabel('time(sec)');ylabel(['X', num2str(i),'(t)']);
                            title(['Sample function ', num2str(i), ' of the ensemble']); grid on;
                            end
                          
                        else 
                              subplot(M/2, 2, i); plot(app.t, app.X(i,:)); xlabel('time(sec)');ylabel(['X', num2str(i),'(t)']);
                              title(['Sample function ', num2str(i), ' of the ensemble']); grid on;
                        end
                    end


                case "Ensemble Mean"
                    app.ensemble_mean = mean(app.X);
                    writematrix(app.ensemble_mean, 'Ensemble mean.txt');
                    figure("Name","Ensemble Mean");
                    plot(app.t, app.ensemble_mean); xlabel('time(sec)');ylabel('Mean(X(t))');
                    title('The Ensemble Mean of the process'); grid on;


                case "Time Mean of 'nth' Sample Function"
                    N = app.N3EditField.Value;
                    app.timemeanofN =(1/(app.t(end) - app.t(1)))*(sum(app.X(N,:)) * (app.t(2)- app.t(1)));
                    app.O3EditField.Enable = 'on';
                    app.O3EditField.Value = app.timemeanofN;


                case "Statistical Auto-Correlation Function between 'i' and 'j' samples"
                    I = app.I4EditField.Value;
                    J = app.J4EditField.Value;
                    ACFIJ = app.X(:,I) .* app.X(:,J);
                    app.ACF_IJ = mean(ACFIJ);
                    app.O4EditField.Value = app.ACF_IJ;
                    
                    
                case "ACF between every i and j Samples"
                    [r, c] = size(app.X);
                    app.TimeACF_Mat = app.X' * app.X;
                    app.TimeACF_Mat = app.TimeACF_Mat./c;
                    figure('Name','ACF between every i and j Samples');
                    a = surf(app.TimeACF_Mat);
                    set(a, 'LineStyle','none');
                    title("ACF between every i and j Samples in the Ensemble");
                    xlabel('i Sample');ylabel('j Sample'); zlabel('Amplitude');


                 case "Time Auto-Correlation Function of the 'nth' Sample Function"
                    N = app.N5EditField.Value;
                    [r, c] = size(app.X);
                    app.ACF_N = zeros(1, c);
                    dt = app.t(2) - app.t(1);
                    
                    for i = 0:c-1
                        app.ACF_N(i+1) = 0;
                        
                        for j = 1:c
                           if (i+j <= c)
                           app.ACF_N(i+1) = app.ACF_N(i+1) + (( app.X(N, j) * app.X(N, j+i)));
                           end
                            
                        end
                        app.ACF_N(i+1) = (1/(app.t(end) - app.t(1)))*dt*app.ACF_N(i+1);
                        
                    end
                    
                    taw = (linspace(-c, c, 2*c)).* (app.t(2) - app.t(1));
                    negsideTACF = fliplr(app.ACF_N);
                    app.ACF_N = [negsideTACF app.ACF_N];
                    figure("Name",'Time Auto-Correlation');
                    plot(taw, app.ACF_N); xlabel('Tau(sec)');ylabel(['ACF(X', num2str(N), '(t))']); xlim([taw(1) taw(end)]);
                    title(['Time Auto-Correlation Function of ' , num2str(N),'th sample function']); grid on;
                    writematrix(app.ACF_N, "Time_ACF.txt");
                    writematrix(taw, "Tau_T_ACF.txt");
                    
                case "Power Spectral Density of The Process"
                    [r, c] = size(app.X);
                    
                    %PSD
                    app.PSD = zeros(1, c);
                    for i = 1:r
                        temp = fftshift(fft(app.X(i,:)));
                        temp = abs(temp/c).^2;
                        app.PSD = app.PSD + temp;    
                    end
                    f = linspace(-50, 50, c);
                    app.PSD = app.PSD / r;
 
                    figure("Name","Power Spectral Density");
                    plot(f, app.PSD); xlabel('Frequency(Hz)');ylabel('S(w)');
                    title('Power Spectral Density of The Process'); grid on; xlim([f(1) f(end)]);
                    writematrix(app.PSD, "PSD.txt");
                    
                case "Ensemble ACF"
                    [r, c] = size(app.X);
                    app.En_ACF = zeros(1, c);
                    
                    %Ensemble ACF
                    for i = 0:c-1
                        tempv = mean((app.X(:, [1: c-i]).* app.X(:, [i+1:c]))');
                        app.En_ACF(i+1)=  mean(tempv');
                    end
                    negside = fliplr(app.En_ACF);
                    app.Double_ACF = [negside app.En_ACF];
                    
                    taw = (linspace(-c, c, 2*c)).* (app.t(2) - app.t(1));
                    figure("Name","Ensemble ACF");
                    plot(taw, app.Double_ACF); xlabel('Taw(sec)');ylabel(['Ensemble ACF']); xlim([taw(1) taw(end)]);
                    title('Ensemble Auto-Correlation Function of The Process'); grid on; 
                    writematrix(app.Double_ACF, "Ensemble_ACF.txt");
                    writematrix(taw, "Tau_ACF.txt");
                    
                    
                case "Total Average Power of The Process"
                    [r, c] = size(app.X);
                    app.En_ACF = zeros(1, c);
                    %Ensemble ACF
                    for i = 0:c-1
                        tempv = mean((app.X(:, [1: c-i]).* app.X(:, [i+1:c]))');
                        app.En_ACF(i+1)=  mean(tempv');
                    end
                    app.O7EditField.Value = app.En_ACF(1);
                    
                case "----ENTER NEW DATA----"
                    
                    app.M1EditField.Visible = 'off';
                    app.M1EditField.Value = 0;
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                    
                    app.N3EditField.Visible = 'off';
                    app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
                    app.O3EditField.Visible = 'off';
                    app.O3EditField.Value = 0;
                    app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
                    app.J4EditField.Visible = 'off';
                    app.J4EditField.Value = 0;
                    app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
                    app.I4EditField.Visible = 'off';
                    app.I4EditField.Value = 0;
                    app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
                    app.N5EditField.Visible = 'off';
                    app.N5EditField.Value = 0;
                    app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
                    app.O7EditField.Visible = 'off';
                    app.O7EditField.Value = 0;
                    app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
                    
                    app.NofileLabel.Visible = 'off';
                    app.OptionsDropDownLabel.Visible = 'off';
                    app.OptionsDropDown.Visible = 'off';
                    app.DONEButton.Visible = 'off';
                
                    app.M1EditField.Visible = 'off';
                    app.M1EditField.Value = 0;
                    app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
                
                    app.AmplitudeFileEditField.Visible = 'on';
                    app.AmplitudeFileNameegAmplitudetxtEditFieldLabel.Visible = 'on';
                    app.TimeFileEditField.Visible = 'on';
                    app.TimeFileNameegTimetxtLabel.Visible = 'on';
                    app.EnterButton.Visible = 'on';


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STOCHASTICPROCESSESCALCULATORLabel = uilabel(app.UIFigure);
            app.STOCHASTICPROCESSESCALCULATORLabel.Text = 'STOCHASTIC PROCESSES CALCULATOR';
            app.AmplitudeFileNameegAmplitudetxtEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeFileNameegAmplitudetxtEditFieldLabel.Text = 'Amplitude File Name (eg. Amplitude.txt)';
            app.AmplitudeFileEditField = uieditfield(app.UIFigure, 'text');
            app.TimeFileNameegTimetxtLabel = uilabel(app.UIFigure);
            app.TimeFileNameegTimetxtLabel.Text = 'Time File Name (eg. Time.txt)';
            app.TimeFileEditField = uieditfield(app.UIFigure, 'text');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = {'Enter'; ''};
            app.OptionsDropDownLabel = uilabel(app.UIFigure);
            app.OptionsDropDownLabel.Visible = 'off';
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.UIFigure);
            app.OptionsDropDown.Items = {'Plot ''M'' Sample Functions', 'Ensemble Mean', 'Time Mean of ''nth'' Sample Function', 'Statistical Auto-Correlation Function between ''i'' and ''j'' samples', 'ACF between every i and j Samples', 'Time Auto-Correlation Function of the ''nth'' Sample Function', 'Power Spectral Density of The Process', 'Ensemble ACF', 'Total Average Power of The Process', '----ENTER NEW DATA----'};
            app.OptionsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OptionsDropDownOpening, true);
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Visible = 'off';
            app.OptionsDropDown.Value = 'Plot ''M'' Sample Functions';
            app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Visible = 'off';
            app.EnterthenumberofsamplefunctionstobedrawnEditFieldLabel.Text = 'Enter the number of sample functions to be drawn';
            app.M1EditField = uieditfield(app.UIFigure, 'numeric');
            app.M1EditField.Visible = 'off';
            app.EnterthenumberofsamplefunctiontimemeanLabel = uilabel(app.UIFigure);
            app.EnterthenumberofsamplefunctiontimemeanLabel.Visible = 'off';
            app.EnterthenumberofsamplefunctiontimemeanLabel.Text = 'Enter the number of sample function "time mean"';
            app.N3EditField = uieditfield(app.UIFigure, 'numeric');
            app.N3EditField.Visible = 'off';
            app.EnterthenumberoffirstsamplefunctionLabel = uilabel(app.UIFigure);
            app.EnterthenumberoffirstsamplefunctionLabel.Visible = 'off';
            app.EnterthenumberoffirstsamplefunctionLabel.Text = 'Enter the number of first sample function';
            app.I4EditField = uieditfield(app.UIFigure, 'numeric');
            app.I4EditField.Visible = 'off';
            app.EnterthenumberofsecondsamplefunctionLabel = uilabel(app.UIFigure);
            app.EnterthenumberofsecondsamplefunctionLabel.Visible = 'off';
            app.EnterthenumberofsecondsamplefunctionLabel.Text = 'Enter the number of second sample function';
            app.J4EditField = uieditfield(app.UIFigure, 'numeric');
            app.J4EditField.Visible = 'off';
            app.EnterthenumberofsamplefunctiontimeautocorrelationLabel = uilabel(app.UIFigure);
            app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Visible = 'off';
            app.EnterthenumberofsamplefunctiontimeautocorrelationLabel.Text = 'Enter the number of sample function "time auto-correlation"';
            app.N5EditField = uieditfield(app.UIFigure, 'numeric');
            app.N5EditField.Visible = 'off';
            app.DONEButton = uibutton(app.UIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Visible = 'off';
            app.DONEButton.Text = 'DONE';
            app.ThetotalaveragepoweroftheprocessLabel = uilabel(app.UIFigure);
            app.ThetotalaveragepoweroftheprocessLabel.Visible = 'off';
            app.ThetotalaveragepoweroftheprocessLabel.Text = 'The total average power of the process';
            app.O7EditField = uieditfield(app.UIFigure, 'numeric');
            app.O7EditField.Editable = 'off';
            app.O7EditField.Visible = 'off';
            app.NofileLabel = uilabel(app.UIFigure);
            app.NofileLabel.Visible = 'off';
            app.NofileLabel.Text = '';
            app.ThetimemeanofthenthsamplefunctionLabel_2 = uilabel(app.UIFigure);
            app.ThetimemeanofthenthsamplefunctionLabel_2.Visible = 'off';
            app.ThetimemeanofthenthsamplefunctionLabel_2.Text = 'The time mean of the nth sample function';
            app.O3EditField = uieditfield(app.UIFigure, 'numeric');
            app.O3EditField.Editable = 'off';
            app.O3EditField.Visible = 'off';
            app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel = uilabel(app.UIFigure);
            app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Visible = 'off';
            app.StatisticalautocorrelationbetweenthetwosamplefunctionsLabel.Text = 'Statistical auto-correlation between the two sample functions';
            app.O4EditField = uieditfield(app.UIFigure, 'numeric');
            app.O4EditField.Editable = 'off';
            app.O4EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end